classdef w13_s1_RobotArm_in_PolorAxes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        PanelConnect     matlab.ui.container.Panel
        ButtonConnect    matlab.ui.control.Button
        DropDownCOMList  matlab.ui.control.DropDown
        Label_2          matlab.ui.control.Label
        PanelMotion      matlab.ui.container.Panel
        ButtonRun        matlab.ui.control.Button
        Lamp             matlab.ui.control.Lamp
        ButtonCheck      matlab.ui.control.Button
        yEditField       matlab.ui.control.NumericEditField
        yEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.NumericEditField
        xEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        L1          % 后臂长度
        L2          % 前臂长度
        Pax         % 极坐标系
        serialObj   % 串口对象
    end
    
    methods (Access = private)
        
        % 逆运动学：(x,y) to (q1,q2)
        function [theta1,theta2,limit_exc] = InverseKinDis(~, x,y,l1,l2)
            r=sqrt(x^2+y^2);
            a=(r^2+l1^2-l2^2)/2/r/l1;
            
            limit_exc=false;  % 超限判断
            if a>=1
                limit_exc=true;
                theta1=0;
                theta2=0;
                return
            end
            
            b=sqrt(1-a^2);
            c=x/r;
            s=y/r;
            c1=c*a+s*b;
            s1=s*a-c*b;
            theta1=atan2(s1,c1);
            ax=l1*c1;
            ay=l1*s1;
            bx=x-ax;
            by=y-ay;
            c2=(ax*bx+ay*by)/l1/l2;
            s2=sqrt(1-c2^2);
            theta2=atan2(s2,c2);
        end
        
        % 正运动学：(q1,q2) to (x,y)
        function [x,y]=ForwardKinDis(~,theta1,theta2,l1,l2)
            x=l1*cos(theta1)+l2*cos(theta1+theta2);
            y=l1*sin(theta1)+l2*sin(theta1+theta2);
        end
        
        function DrawArms(app,q1,q2)
            % 臂杆顶点极坐标
            [r,q] = app.ArmVertexesInPolar(q1,q2);
            cla(app.Pax,'reset')
            hold(app.Pax, "on");
            r = r*1000;
            polarplot(app.Pax, q, r,'b', "linewidth",2);
            polarplot(app.Pax, q, r,'bo',"linewidth",1);
            app.Pax.ThetaLim = [-120 120];            % 角度显示范围
            app.Pax.RLim = [0 1000*(app.L1+app.L2)];  % 半径显示范围
            app.Pax.RTickLabel = {};                  % 关闭径向坐标刻度
            app.Pax.ThetaTickLabel  = {};             % 关闭轴向坐标刻度
        end
        
        function [rho,theta] = ArmVertexesInPolar(app,q1,q2)
            [x1,y1] = app.ForwardKinDis(q1,q2,app.L1,0);
            [x2,y2] = app.ForwardKinDis(q1,q2,app.L1,app.L2);
            x = [0 x1 x2];
            y = [0 y1 y2];
            z = complex(x,y);
            rho = abs(z);
            theta = angle(z);
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.L1 = 0.2;  app.L2 = 0.15;                  % 定义机械臂长度

 %           COM_List = serialportlist("available");
            COM_List = {'COM22','COM48'};                  % 用于调试
            app.DropDownCOMList.Items = COM_List; % 填充串口列表
            
            app.Pax = polaraxes(app.UIFigure);             % 创建极坐标系
            app.Pax.Units = 'pixels';
            app.Pax.Position = [250 50 300 300];
            
            % 绘制机械臂位置示意图
            x = app.xEditField.Value/1000;                 % 末端笛卡尔坐标
            y = app.yEditField.Value/1000;
            [q1, q2, ~] = app.InverseKinDis(x,y,app.L1,app.L2); % 关节坐标
            app.DrawArms(q1,q2);       % 绘制臂杆示意图
        end

        % Button pushed function: ButtonCheck
        function ButtonCheckPushed(app, event)
            % 逆运动学解算，检测目标位置正确性
            x = app.xEditField.Value/1000; % 末端x坐标(m)
            y = app.yEditField.Value/1000; % 末端y坐标(m)
            [q1,q2,err]=app.InverseKinDis(x,y,app.L1,app.L2); % 关节坐标
            if err == 0 % 检测通过
                app.Lamp.Color = [0,1,0];  % 指示灯绿色
                app.ButtonRun.Enable = 1;  % 启动按钮使能
                app.DrawArms(q1,q2);       % 绘制臂杆示意图
            else
                app.Lamp.Color = [1,0,0];  % 指示灯红色
                app.ButtonRun.Enable = 0;  % 启动按钮失效
            end
        end

        % Button pushed function: ButtonRun
        function ButtonRunPushed(app, event)
            x = app.xEditField.Value/1000; % 末端x坐标(m)
            y = app.yEditField.Value/1000; % 末端y坐标(m)
            [theta1,theta2,~]=app.InverseKinDis(x,y,app.L1,app.L2); % 关节坐标
            q1 = theta1*180/pi;
            q2 = theta2*180/pi;
            writeline(app.serialObj, [num2str(q1),',',num2str(q2)]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.serialObj);
            delete(app)
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            app.Lamp.Color = [0.50,0.50,0.50];
            app.ButtonRun.Enable = 0;
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            app.Lamp.Color = [0.50,0.50,0.50];
            app.ButtonRun.Enable = 0;
        end

        % Button pushed function: ButtonConnect
        function ButtonConnectPushed(app, event)
            app.serialObj = serialport(app.DropDownCOMList.Value,115200);
            app.ButtonConnect.Enable = 0;  % 不能再次连接串口
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '二轴平面机械臂运动控制';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PanelMotion = uipanel(app.UIFigure);
            app.PanelMotion.Title = '末端坐标设置(mm)';
            app.xEditFieldLabel = uilabel(app.PanelMotion);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.PanelMotion, 'numeric');
            app.xEditField.ValueDisplayFormat = '%8.2f';
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField.Value = 56;
            app.yEditFieldLabel = uilabel(app.PanelMotion);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.PanelMotion, 'numeric');
            app.yEditField.ValueDisplayFormat = '%8.2f';
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.yEditField.Value = 150;
            app.ButtonCheck = uibutton(app.PanelMotion, 'push');
            app.ButtonCheck.ButtonPushedFcn = createCallbackFcn(app, @ButtonCheckPushed, true);
            app.ButtonCheck.Text = '干涉检查';
            app.Lamp = uilamp(app.PanelMotion);
            app.ButtonRun = uibutton(app.PanelMotion, 'push');
            app.ButtonRun.ButtonPushedFcn = createCallbackFcn(app, @ButtonRunPushed, true);
            app.ButtonRun.Enable = 'off';
            app.ButtonRun.Text = '运动执行';
            app.PanelConnect = uipanel(app.UIFigure);
            app.PanelConnect.Title = '串口设定';
            app.Label_2 = uilabel(app.PanelConnect);
            app.Label_2.Text = '选择';
            app.DropDownCOMList = uidropdown(app.PanelConnect);
            app.ButtonConnect = uibutton(app.PanelConnect, 'push');
            app.ButtonConnect.ButtonPushedFcn = createCallbackFcn(app, @ButtonConnectPushed, true);
            app.ButtonConnect.Text = '连接';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = w13_s1_RobotArm_in_PolorAxes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end