classdef w13_s2_RobotArm_TeachPendant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Spinner2         matlab.ui.control.Spinner
        coor2Label       matlab.ui.control.Label
        Spinner1         matlab.ui.control.Spinner
        coor1Label       matlab.ui.control.Label
        ButtonGroup      matlab.ui.container.ButtonGroup
        Button_CoorE     matlab.ui.control.RadioButton
        Button_CoorB     matlab.ui.control.RadioButton
        Button_CoorJ     matlab.ui.control.RadioButton
        PanelConnect     matlab.ui.container.Panel
        ButtonConnect    matlab.ui.control.Button
        DropDownCOMList  matlab.ui.control.DropDown
        Label_2          matlab.ui.control.Label
    end

    
    properties (Access = private)
        L1          % 后臂长度
        L2          % 前臂长度
        PrevSpinner1
        PrevSpinner2
        hLabel1
        hLabel2
        serialObj   % 串口对象
        q1          % 关节坐标1(°)
        q2          % 关节坐标2(°)
    end
    
    methods (Access = private)
        
        % 逆运动学：(x,y) to (q1,q2)
        function [theta1,theta2,limit_exc] = InverseKinDis(~, x,y,l1,l2)
            r=sqrt(x^2+y^2);
            a=(r^2+l1^2-l2^2)/2/r/l1;
            
            limit_exc=false;  % 超限判断
            if a>=1
                limit_exc=true;
                theta1=0;
                theta2=0;
                return
            end
            
            b=sqrt(1-a^2);
            c=x/r;
            s=y/r;
            c1=c*a+s*b;
            s1=s*a-c*b;
            theta1=atan2(s1,c1);
            ax=l1*c1;
            ay=l1*s1;
            bx=x-ax;
            by=y-ay;
            c2=(ax*bx+ay*by)/l1/l2;
            s2=sqrt(1-c2^2);
            theta2=atan2(s2,c2);
        end
        
        % 正运动学：(q1,q2) to (x,y)
        function [x,y]=ForwardKinDis(~,theta1,theta2,l1,l2)
            x=l1*cos(theta1)+l2*cos(theta1+theta2);
            y=l1*sin(theta1)+l2*sin(theta1+theta2);
        end
        
        function ReadJointerCoor(app, src, ~)
            tline = readline(src);  % src = app.serialobj
            xy = sscanf(tline,"%d,%d");
            app.q1 = xy(1)/50.0;   % 360/18000
            app.q2 = xy(2)/50.0;   % 360/18000
            app.Spinner1.Enable = "on";
            app.Spinner2.Enable = "on";
        end
        
        function SpinnerValueChanged(app)
            selectedButton = app.ButtonGroup.SelectedObject;
            Q1 = [];
            Q2 = [];
            switch selectedButton.Text
                case '关节坐标系'
                    Q1 = app.Spinner1.Value;
                    Q2 = app.Spinner2.Value;
                case '本体坐标系'
                    X = app.Spinner1.Value/1000;
                    Y = app.Spinner2.Value/1000;
                    [theta1,theta2,err] = app.InverseKinDis(X,Y,app.L1,app.L2);
                    if err == 0
                        Q1 = theta1*180/pi;
                        Q2 = theta2*180/pi;
                    end
                case '末端坐标系'
            end
            if ~isempty(Q1) && ~isempty(Q2)
                %                 [Q1,Q2]
                if ~isempty(app.serialObj) 
                    writeline(app.serialObj, [num2str(Q1),',',num2str(Q2)]);
                    app.Spinner1.Enable = "off";
                    app.Spinner2.Enable = "off";
                end
            end
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.L1 = 0.2;  app.L2 = 0.15;            % 定义机械臂长度
            app.q1 = 0;    app.q2 = 0;               % 约定初始关节坐标

%             COM_List = serialportlist("available");
            COM_List = {'COM22','COM48'};             % 用于调试
            app.DropDownCOMList.Items = COM_List;     % 填充串口列表
            app.serialObj = [];

            chld = get(app.UIFigure,'children');
            for n=1:length(chld)
                if get(chld(n), 'Type') == "uilabel"
                    if get(chld(n),'text') == "coor1"
                        app.hLabel1 = chld(n);
                    end
                    if get(chld(n),'text') == "coor2"
                        app.hLabel2 = chld(n);
                    end
                end
            end
            set(app.hLabel1,'text', "q1(°)");
            set(app.hLabel2,'text', "q2(°)");
            app.Spinner1.Step = 3;
            app.Spinner2.Step = 3;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.serialObj)
                writeline(app.serialObj, [num2str(-5500/50),',',num2str(7200/50)]);
                pause(0.5);
            end
            delete(app.serialObj);
            delete(app)
        end

        % Button pushed function: ButtonConnect
        function ButtonConnectPushed(app, event)
            app.serialObj = serialport(app.DropDownCOMList.Value,115200);
            configureTerminator(app.serialObj,"CR/LF","LF");
            callbackFcnHndl = @app.ReadJointerCoor;
            configureCallback(app.serialObj,"terminator",callbackFcnHndl);
            app.ButtonConnect.Enable = 0;  % 不能再次连接串口
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case '关节坐标系'
                    set(app.hLabel1,'text', "q1(°)");
                    set(app.hLabel2,'text', "q2(°)");
                    app.Spinner1.Step = 3;
                    app.Spinner2.Step = 3;
                    app.Spinner1.Value = app.q1;
                    app.Spinner2.Value = app.q2;
                case '本体坐标系'
                    set(app.hLabel1,'text', "X(mm)");
                    set(app.hLabel2,'text', "Y(mm)");
                    app.Spinner1.Step = 5;
                    app.Spinner2.Step = 5;
                    theta1 = app.q1*pi/180;
                    theta2 = app.q2*pi/180;
                    [X,Y] = app.ForwardKinDis(theta1,theta2,app.L1,app.L2);
                    app.Spinner1.Value = X*1000;
                    app.Spinner2.Value = Y*1000;
                case '末端坐标系'
                    set(app.hLabel1,'text', "x(mm)");
                    set(app.hLabel2,'text', "y(mm)");
            end
        end

        % Value changed function: Spinner1
        function Spinner1ValueChanged(app, event)
            app.SpinnerValueChanged;
        end

        % Value changed function: Spinner2
        function Spinner2ValueChanged(app, event)
            app.SpinnerValueChanged;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '示教器';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PanelConnect = uipanel(app.UIFigure);
            app.PanelConnect.Title = '串口设定';
            app.Label_2 = uilabel(app.PanelConnect);
            app.Label_2.Text = '选择';
            app.DropDownCOMList = uidropdown(app.PanelConnect);
            app.ButtonConnect = uibutton(app.PanelConnect, 'push');
            app.ButtonConnect.ButtonPushedFcn = createCallbackFcn(app, @ButtonConnectPushed, true);
            app.ButtonConnect.Text = '连接';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '选择';
            app.Button_CoorJ = uiradiobutton(app.ButtonGroup);
            app.Button_CoorJ.Text = '关节坐标系';
            app.Button_CoorJ.Value = true;
            app.Button_CoorB = uiradiobutton(app.ButtonGroup);
            app.Button_CoorB.Text = '本体坐标系';
            app.Button_CoorE = uiradiobutton(app.ButtonGroup);
            app.Button_CoorE.Text = '末端坐标系';
            app.coor1Label = uilabel(app.UIFigure);
            app.coor1Label.Text = 'coor1';
            app.Spinner1 = uispinner(app.UIFigure);
            app.Spinner1.ValueChangedFcn = createCallbackFcn(app, @Spinner1ValueChanged, true);
            app.Spinner1.Editable = 'off';
            app.coor2Label = uilabel(app.UIFigure);
            app.coor2Label.Text = 'coor2';
            app.Spinner2 = uispinner(app.UIFigure);
            app.Spinner2.ValueChangedFcn = createCallbackFcn(app, @Spinner2ValueChanged, true);
            app.Spinner2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = w13_s2_RobotArm_TeachPendant

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end