classdef ManipulatorSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        LabelTitle        matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        SpinnerTime       matlab.ui.control.Spinner
        DropDownLink      matlab.ui.control.DropDown
        Label             matlab.ui.control.Label
        SliderAmplitude   matlab.ui.control.Slider
        SpinnerAmplitude  matlab.ui.control.Spinner
        SliderFrequency   matlab.ui.control.Slider
        Label_3           matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        sLabel            matlab.ui.control.Label
        SpinnerFrequency  matlab.ui.control.Spinner
        ButtonStart       matlab.ui.control.StateButton
        ButtonReset       matlab.ui.control.Button
        ButtonWorkspace   matlab.ui.control.StateButton
        SpinnerNsamples   matlab.ui.control.Spinner
        ButtonClosed      matlab.ui.control.Button
        LabelFigureTitle  matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        L_all = [12, 10, 8, 6];
        theta0_all = [0, 30, -90, 60];
        A_all;
        f_all;
        tmax = 30;
        tstart = 0;
        tpause = 0;

        hLinks;
        V_link0;
        F_link;
        colors;
        hJoints;
        V_joint0;
        F_joint;
        hFrame;
        hTrace;
        DH;
        q_limit = [-160, 160;   % Joint 1
                   -110, 110;   % Joint 2
                   -130, 130;   % Joint 3
                   -150, 150];  % Joint 4
        hWorkspace;

        IsReset = false;
        IsRunning = false;
    end
    
    methods (Access = private)
        
        % 齐次变换矩阵
        function T = TransMatrix(~, alpha, a, theta, d)
            T = [cosd(theta), -sind(theta), 0, a;
                sind(theta)*cosd(alpha), cosd(theta)*cosd(alpha), -sind(alpha), -sind(alpha)*d;
                sind(theta)*sind(alpha), cosd(theta)*sind(alpha), cosd(alpha), cosd(alpha)*d;
                0, 0, 0, 1];
        end
        
        % 创建圆柱体
        function [V, F] = creatCylinder(~, r, h, n)
            % r:半径, h:高度, n:分段数
            % 创建圆柱面
            [x, y, z] = cylinder(r, n);
            z = z * h;
            % 顶点
            V = [x(1,:)', y(1,:)', z(1,:)';  % 侧面下底圆顶点
                x(2,:)', y(2,:)', z(2,:)';   % 侧面上底圆顶点
                0, 0, 0;                     % 下底面圆心顶点
                0, 0, h];                    % 上底面圆心顶点
            % 补片
            F = zeros(3*n, 4);
            for i = 1:n
                % 侧面
                F(i,:) = [i, i+1, i+n+2, i+n+1];
                % 下底面
                F(i+n,:) = [i, i+1, 2*n+3, 2*n+3];
                % 上底面
                F(i+2*n,:) = [i+n+1, i+n+2, 2*n+4, 2*n+4];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % UI按钮初始化
            app.DropDownLink.Items = {'连杆1', '连杆2', '连杆3', '连杆4'};
            app.SliderAmplitude.Limits = [-160, 160];
            app.SliderFrequency.Limits = [0, 1];
            app.A_all = [30, 10, 20, 30];
            app.f_all = [0.1, 0.15, 0.1, 0.2];
            app.SpinnerTime.Value = 30;
            [~, idx] = ismember(app.DropDownLink.Value, app.DropDownLink.Items);
            app.SliderAmplitude.Value = app.A_all(idx);
            app.SpinnerAmplitude.Value = app.A_all(idx);
            app.SliderFrequency.Value = app.f_all(idx);
            app.SpinnerFrequency.Value = app.f_all(idx);
            app.ButtonStart.Text = '开始';
            app.SpinnerNsamples.Value = 10000;
            app.ButtonStart.Enable = false;
            app.ButtonReset.Enable = false;
            app.ButtonWorkspace.Enable = false;
            
            % 创建机械臂实体
            % 画布参数调整
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            view(app.UIAxes, [30, 15]);
            axis(app.UIAxes, 'equal');

            % 创建底座
            [V_bottom, F_bottom] = creatCylinder(app, 6, 2, 40);
            patch(app.UIAxes, 'Vertices', V_bottom, 'Faces', F_bottom, ...
                'FaceColor', [0.2 0.2 0.2], 'EdgeColor', 'none');

            % 创建连杆
            r_link = 1.2;
            [app.V_link0, app.F_link] = creatCylinder(app, r_link, 1, 20);
            app.hLinks = cell(1,4);
            app.colors = sky(4);
            V_link1 = [app.V_link0(:,1:2), app.V_link0(:,3) * app.L_all(1)];
            app.hLinks{1} = patch(app.UIAxes, 'Vertices', V_link1, 'Faces', app.F_link, ...
                            'FaceColor', app.colors(1,:), 'EdgeColor', 'none');
            for j = 2:4
                app.hLinks{j} = patch(app.UIAxes, 'Vertices', [], 'Faces', [], ...
                                'FaceColor', app.colors(j,:), 'EdgeColor', 'none');
            end

            % 创建关节
            r_joint = 1.5;
            h_joint = 5;
            [app.V_joint0, app.F_joint] = creatCylinder(app, r_joint, h_joint, 20);
            app.V_joint0(:,3) = app.V_joint0(:,3) - h_joint/2;
            app.hJoints = cell(1,4);
            for j = 1:4
                app.hJoints{j} = patch(app.UIAxes, 'Vertices', [], 'Faces', [], ...
                                'FaceColor', [0.75 0.75 0.75], 'EdgeColor', 'none');
            end

            % 创建末端坐标系
            app.hFrame = cell(1,3);
            colors_frame = ['r', 'g', 'b'];
            for l = 1:3
                app.hFrame{l} = quiver3(app.UIAxes, 0, 0, 0, 0, 0, 0, ...
                    'Color', colors_frame(l), 'LineWidth', 2);
            end

            % 创建末端轨迹
            app.hTrace = animatedline(app.UIAxes, 'Color', [0.95 0.75 0.1], 'LineWidth', 1.5);

            % 渲染
            camlight(app.UIAxes, 'headlight');
            material(app.UIAxes, 'dull');

            % 初始化机械臂模型
            T = eye(4);
            app.DH = [0, 0, app.theta0_all(1), app.L_all(1);
                    90, 0, app.theta0_all(2), 0;
                    0, app.L_all(2), app.theta0_all(3), 0;
                    0, app.L_all(3), app.theta0_all(4), 0;
                    0, app.L_all(4), 0, 0];
            for j = 1:5
                alpha = app.DH(j,1);
                a = app.DH(j,2);
                theta = app.DH(j,3);
                d = app.DH(j,4);
                T = T * TransMatrix(app, alpha, a, theta, d);
                if j <= 4
                    % 更新关节
                    V_joint = (T(1:3,1:3) * app.V_joint0' + T(1:3,4))';
                    set(app.hJoints{j}, 'Vertices', V_joint, 'Faces', app.F_joint);
                    if j ~= 1
                        % 更新连杆
                        V_linkj = [app.V_link0(:,3) * app.L_all(j), app.V_link0(:,1:2)];
                        V_link = (T(1:3,1:3) * V_linkj' + T(1:3,4))';
                        set(app.hLinks{j}, 'Vertices', V_link, 'Faces', app.F_link);
                    end
                end
            end
            % 更新末端坐标系
            for l = 1:3
                set(app.hFrame{l}, 'XData', T(1,4), 'YData', T(2,4), 'ZData', T(3,4), ...
                    'UData', T(1,l)*5, 'VData', T(2,l)*5, 'WData', T(3,l)*5);
            end
            axis(app.UIAxes, [-30 30 -30 30 0 40]);
            app.LabelFigureTitle.Text = 'Time: 0.00s    Position: [0.00, 0.00, 0.00]';
            drawnow limitrate;

            app.ButtonStart.Enable = true;
            app.ButtonReset.Enable = true;
            app.ButtonWorkspace.Enable = true;

            tic;
        end

        % Value changed function: SpinnerTime
        function SpinnerTimeValueChanged(app, event)
            app.tmax = app.SpinnerTime.Value;
        end

        % Value changed function: DropDownLink
        function DropDownLinkValueChanged(app, event)
            [~, idx] = ismember(app.DropDownLink.Value, app.DropDownLink.Items);
            app.SliderAmplitude.Limits = app.q_limit(idx,:);
            app.SliderAmplitude.Value = app.A_all(idx);
            app.SpinnerAmplitude.Value = app.A_all(idx);
            app.SliderFrequency.Value = app.f_all(idx);
            app.SpinnerFrequency.Value = app.f_all(idx);
        end

        % Value changed function: SliderAmplitude, SliderFrequency
        function SliderValueChanged(app, event)
            [~, idx] = ismember(app.DropDownLink.Value, app.DropDownLink.Items);
            app.A_all(idx) = app.SliderAmplitude.Value;
            app.f_all(idx) = app.SliderFrequency.Value;
            app.SpinnerAmplitude.Value = app.A_all(idx);
            app.SpinnerFrequency.Value = app.f_all(idx);
        end

        % Value changed function: SpinnerAmplitude, SpinnerFrequency
        function SpinnerValueChanged(app, event)
            [~, idx] = ismember(app.DropDownLink.Value, app.DropDownLink.Items);
            app.A_all(idx) = app.SpinnerAmplitude.Value;
            app.f_all(idx) = app.SpinnerFrequency.Value;
            app.SliderAmplitude.Value = app.A_all(idx);
            app.SliderFrequency.Value = app.f_all(idx);
        end

        % Value changed function: ButtonStart
        function ButtonStartValueChanged(app, event)
            try
                start = app.ButtonStart.Value;
                if start == true
                    app.ButtonStart.Text = '暂停';
                    app.IsRunning = true;
                    app.IsReset = false;
                    % 开始仿真时不可以调整参数
                    app.SpinnerTime.Enable = false;
                    app.DropDownLink.Enable = false;
                    app.SpinnerAmplitude.Enable = false;
                    app.SpinnerFrequency.Enable = false;
                    app.SliderAmplitude.Enable = false;
                    app.SliderFrequency.Enable = false;
                    app.tstart = toc;
                    while toc-app.tstart+app.tpause <= app.tmax
                        % 重置优先级更高
                        if app.IsReset == true
                            app.IsReset = false;
                            break;
                        end
                        % 暂停优先级更高
                        if app.IsRunning == false
                            % app.IsRunning = true;
                            break;
                        end
    
                        % 计算关节角度
                        theta_all = app.theta0_all + app.A_all .* sin(2*pi * app.f_all * (toc - app.tstart + app.tpause));
                    
                        % 正运动学计算
                        T = eye(4);
                        app.DH(1:4,3) = theta_all;
                        for j = 1:5
                            alpha = app.DH(j,1);
                            a = app.DH(j,2);
                            theta = app.DH(j,3);
                            d = app.DH(j,4);
                            T = T * TransMatrix(app, alpha, a, theta, d);
                            if j <= 4
                                % 更新关节
                                V_joint = (T(1:3,1:3) * app.V_joint0' + T(1:3,4))';
                                set(app.hJoints{j}, 'Vertices', V_joint, 'Faces', app.F_joint);
                                % 碰撞检测
                                if any(V_joint(:,3)<0)
                                    set(app.hJoints{j}, 'FaceColor', [0.6 0.3 0]);
                                else
                                    set(app.hJoints{j}, 'FaceColor', [0.75 0.75 0.75]);
                                end
                                if j ~= 1
                                    % 更新连杆
                                    V_linkj = [app.V_link0(:,3) * app.L_all(j), app.V_link0(:,1:2)];
                                    V_link = (T(1:3,1:3) * V_linkj' + T(1:3,4))';
                                    set(app.hLinks{j}, 'Vertices', V_link, 'Faces', app.F_link);
                                    % 碰撞检测
                                    if any(V_link(:,3)<0)
                                        set(app.hLinks{j}, 'FaceColor', [1 0.5 0]);
                                    else
                                        set(app.hLinks{j}, 'FaceColor', app.colors(j,:));
                                    end
                                end
                            end
                        end
                        % 更新末端坐标系
                        for l = 1:3
                            set(app.hFrame{l}, 'XData', T(1,4), 'YData', T(2,4), 'ZData', T(3,4), ...
                                'UData', T(1,l)*5, 'VData', T(2,l)*5, 'WData', T(3,l)*5);
                        end
                    
                        % 更新末端轨迹
                        pos = T(1:3,4);
                        addpoints(app.hTrace, pos(1), pos(2), pos(3));
                        % 实时信息显示
                        app.LabelFigureTitle.Text = sprintf('Time: %.2fs    Position: [%.2f, %.2f, %.2f]', toc-app.tstart+app.tpause, pos(1), pos(2), pos(3));
                        axis(app.UIAxes, [-30 30 -30 30 0 40]);
                        drawnow limitrate;
                    end
                    app.ButtonStart.Text = '开始';
                    app.ButtonStart.Value = false;
                    % 只有完整仿真结束才能重新调整参数
                    if app.IsRunning == true
                        app.SpinnerTime.Enable = true;
                        app.DropDownLink.Enable = true;
                        app.SpinnerAmplitude.Enable = true;
                        app.SpinnerFrequency.Enable = true;
                        app.SliderAmplitude.Enable = true;
                        app.SliderFrequency.Enable = true;
                    end
                else
                    app.ButtonStart.Text = '开始';
                    app.IsRunning = false;
                    app.tpause = toc - app.tstart + app.tpause;
                end
            catch
                return;
            end
        end

        % Button pushed function: ButtonReset
        function ButtonResetPushed(app, event)
            app.IsReset = true;
            clearpoints(app.hTrace);
            T = eye(4);
            app.DH(1:4,3) = app.theta0_all;
            for j = 1:5
                alpha = app.DH(j,1);
                a = app.DH(j,2);
                theta = app.DH(j,3);
                d = app.DH(j,4);
                T = T * TransMatrix(app, alpha, a, theta, d);
                if j <= 4
                    % 更新关节
                    V_joint = (T(1:3,1:3) * app.V_joint0' + T(1:3,4))';
                    set(app.hJoints{j}, 'Vertices', V_joint, 'Faces', app.F_joint, 'FaceColor', [0.75 0.75 0.75]);
                    if j ~= 1
                        % 更新连杆
                        V_linkj = [app.V_link0(:,3) * app.L_all(j), app.V_link0(:,1:2)];
                        V_link = (T(1:3,1:3) * V_linkj' + T(1:3,4))';
                        set(app.hLinks{j}, 'Vertices', V_link, 'Faces', app.F_link, 'FaceColor', app.colors(j,:));
                    end
                end
            end
            % 更新末端坐标系
            for l = 1:3
                set(app.hFrame{l}, 'XData', T(1,4), 'YData', T(2,4), 'ZData', T(3,4), ...
                    'UData', T(1,l)*10, 'VData', T(2,l)*10, 'WData', T(3,l)*10);
            end
            axis(app.UIAxes, [-30 30 -30 30 0 40]);
            app.LabelFigureTitle.Text = 'Time: 0.00s    Position: [0.00, 0.00, 0.00]';
            app.ButtonStart.Text = '开始';
            app.ButtonStart.Value = false;
            % 重置可以调整参数
            app.SpinnerTime.Enable = true;
            app.DropDownLink.Enable = true;
            app.SpinnerAmplitude.Enable = true;
            app.SpinnerFrequency.Enable = true;
            app.SliderAmplitude.Enable = true;
            app.SliderFrequency.Enable = true;
            app.tpause = 0;
            drawnow limitrate;
        end

        % Value changed function: ButtonWorkspace
        function ButtonWorkspaceValueChanged(app, event)
            if app.ButtonWorkspace.Value == true
                app.SpinnerNsamples.Enable = false;
                N_samples = app.SpinnerNsamples.Value;
                pos = zeros(N_samples, 3);
                for k = 1:N_samples
                    % 取随机关节角
                    q = app.q_limit(:,1) + (app.q_limit(:,2) - app.q_limit(:,1)) .* rand(4,1);
                    % 正运动学求解
                    T = eye(4);
                    app.DH(1:4,3) = q;
                    for j = 1:5
                        alpha = app.DH(j,1);
                        a = app.DH(j,2);
                        theta = app.DH(j,3);
                        d = app.DH(j,4);
                        T = T * TransMatrix(app, alpha, a, theta, d);
                    end
                    pos(k,:) = T(1:3,4)';
                end
                % 绘制云图
                app.hWorkspace = plot3(app.UIAxes, pos(:,1), pos(:,2), pos(:,3), '.', 'Color', [0.9 0.9 1]);
            else
                delete(app.hWorkspace);
                app.SpinnerNsamples.Enable = true;
            end
        end

        % Button pushed function: ButtonClosed
        function ButtonClosedPushed(app, event)
            delete(app.UIFigure);
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.UIFigure);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X (cm)')
            ylabel(app.UIAxes, 'Y (cm)')
            zlabel(app.UIAxes, 'Z (cm)')
            app.UIAxes.XTick = [-30 -20 -10 0 10 20 30];
            app.UIAxes.XTickLabel = {'-30'; '-20'; '-10'; '0'; '10'; '20'; '30'};
            app.UIAxes.YTick = [-30 -20 -10 0 10 20 30];
            app.UIAxes.YTickLabel = {'-30'; '-20'; '-10'; '0'; '10'; '20'; '30'};
            app.UIAxes.ZTick = [0 5 10 15 20 25 30 35 40];
            app.UIAxes.ZTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'};
            app.LabelFigureTitle = uilabel(app.UIFigure);
            app.LabelFigureTitle.Text = '';
            app.ButtonClosed = uibutton(app.UIFigure, 'push');
            app.ButtonClosed.ButtonPushedFcn = createCallbackFcn(app, @ButtonClosedPushed, true);
            app.ButtonClosed.Text = '关闭';
            app.SpinnerNsamples = uispinner(app.UIFigure);
            app.SpinnerNsamples.Step = 100;
            app.SpinnerNsamples.ValueDisplayFormat = '%.0f';
            app.ButtonWorkspace = uibutton(app.UIFigure, 'state');
            app.ButtonWorkspace.ValueChangedFcn = createCallbackFcn(app, @ButtonWorkspaceValueChanged, true);
            app.ButtonWorkspace.Text = '工作空间';
            app.ButtonReset = uibutton(app.UIFigure, 'push');
            app.ButtonReset.ButtonPushedFcn = createCallbackFcn(app, @ButtonResetPushed, true);
            app.ButtonReset.Text = '重置';
            app.ButtonStart = uibutton(app.UIFigure, 'state');
            app.ButtonStart.ValueChangedFcn = createCallbackFcn(app, @ButtonStartValueChanged, true);
            app.ButtonStart.Text = '开始';
            app.SpinnerFrequency = uispinner(app.UIFigure);
            app.SpinnerFrequency.Step = 0.05;
            app.SpinnerFrequency.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = '时间 (s)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '振幅';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '频率';
            app.SliderFrequency = uislider(app.UIFigure);
            app.SliderFrequency.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SliderFrequency.Tag = 'f';
            app.SpinnerAmplitude = uispinner(app.UIFigure);
            app.SpinnerAmplitude.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.SliderAmplitude = uislider(app.UIFigure);
            app.SliderAmplitude.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SliderAmplitude.Tag = 'A';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '选择';
            app.DropDownLink = uidropdown(app.UIFigure);
            app.DropDownLink.Items = {'连杆1', '连杆2', '连杆3', '连杆4'};
            app.DropDownLink.ValueChangedFcn = createCallbackFcn(app, @DropDownLinkValueChanged, true);
            app.DropDownLink.Value = '连杆1';
            app.SpinnerTime = uispinner(app.UIFigure);
            app.SpinnerTime.ValueChangedFcn = createCallbackFcn(app, @SpinnerTimeValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '采集点数';
            app.LabelTitle = uilabel(app.UIFigure);
            app.LabelTitle.Text = '四自由度机械臂运动学仿真';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipulatorSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end